classdef heaaare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StartAudioButton  matlab.ui.control.StateButton
        StatusLamp        matlab.ui.control.Lamp
        StatusLampLabel   matlab.ui.control.Label
        NotHearButton     matlab.ui.control.Button
        HeardButton       matlab.ui.control.Button
        StartTestButton   matlab.ui.control.Button
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
properties (Access = private)
        Fs = 22050;
        Frequencies = [50 100 200 500 800 1000 1600 2000 4000 6000 10000 12000 16000];
        dBLevels = -60:5:-30; %limited protection
        duration = 2;     % 1s per chunk
        bits = 16;          % Bit depth
        nchannels = 1;
    
        CurrentEar = 'Left';
        CurrentFreqIdx = 1;
        CurrentdBIdx = 1;
    
        ThresholdsLeft
        ThresholdsRight
    
        TestRunning = false;
        
        AudioTimer  timer;
end
    
methods (Access = private)

    function recordChunk(app)
    % Step 1: Record audio (16-bit signed integer format)
    recorder = audiorecorder(app.Fs, 16, 1); % Using mono for cleaner output
    recordblocking(recorder, app.duration);
    audioData = getaudiodata(recorder, 'int16');
    
    % Step 3: Prepare output folder
    folder = fullfile(getenv('USERPROFILE'), 'Desktop', 'Telecom Umut', ...
                    'EE3001_FinalProject_EG_IUD', 'MATLAB files');
    if ~exist(folder, 'dir')
        mkdir(folder);
    end
    filename = fullfile(folder, 'chunk.txt');
    
    fid = fopen(filename, 'w');
    if fid == -1
        error('Failed to open file for writing.');
    end
    
    fprintf(fid, '%d\n', audioData);
    
    fclose(fid);
    
    disp(['Wrote ',  ...
          ' voice samples to: ' filename]);
    end

        
    function playCurrentTone(app)
        freq = app.Frequencies(app.CurrentFreqIdx);
        dB = app.dBLevels(app.CurrentdBIdx);
        t = 0:1/app.Fs:0.5;
        tone = sin(2*pi*freq*t);
        volume = 10^(dB/20);
        signal = volume * tone;

    if app.CurrentEar == "Left"
        stereo = [signal; zeros(size(signal))];
    else
        stereo = [zeros(size(signal)); signal];
    end
        % LED yeşil: ses çalıyor
        app.StatusLamp.Color = [0 1 0];
    
        % Ses çal
        player = audioplayer(stereo', app.Fs);  % dikkat: transpose gerekiyor
        playblocking(player);  % ses bitene kadar bekler
    
        % LED kırmızı: ses bitti
        app.StatusLamp.Color = [1 0 0];
    end

    function moveToNextFrequency(app)
        if app.CurrentFreqIdx < length(app.Frequencies)
            app.CurrentFreqIdx = app.CurrentFreqIdx + 1;
            app.CurrentdBIdx = 1;
            playCurrentTone(app);
        else
            % Sol kulak bittiyse sağa geç
            if app.CurrentEar == "Left"
                app.CurrentEar = "Right";
                app.CurrentFreqIdx = 1;
                app.CurrentdBIdx = 1;
                playCurrentTone(app);
            else
                app.TestRunning = false;
                plotResults(app); % her iki kulak için ayrı grafikleri göster
            end
        end
    end


    function plotResults(app)

        % Sol kulak grafiği
        cla(app.UIAxes);
        semilogx(app.UIAxes, app.Frequencies, app.ThresholdsLeft, '-o', 'Color', 'b');
        xlabel(app.UIAxes, 'Frequency (Hz)');
        ylabel(app.UIAxes, 'Threshold (dB)');
        title(app.UIAxes, 'Left Ear Threshold');
        grid(app.UIAxes, 'on');

        % Sağ kulak grafiği
        cla(app.UIAxes_2);
        semilogx(app.UIAxes_2, app.Frequencies, app.ThresholdsRight, '-x', 'Color', 'r');
        xlabel(app.UIAxes_2, 'Frequency (Hz)');
        ylabel(app.UIAxes_2, 'Threshold (dB)');
        title(app.UIAxes_2, 'Right Ear Threshold');
        grid(app.UIAxes_2, 'on');

        % Step 2: Convert each sample to bits
        thresholds = [app.ThresholdsLeft,app.ThresholdsRight];
        personBits = dec2bin((thresholds+60)/5, 3); % 16-bit binary strings
        allBits = reshape(personBits', [], 1); % column vector of bits (as chars)
        disp(thresholds);
        % Step 3: Prepare output folder
        folder = fullfile(getenv('USERPROFILE'), 'Desktop', 'Telecom Umut', ...
                                  'EE3001_FinalProject_EG_IUD', 'MATLAB files');
        if ~exist(folder, 'dir')
                    mkdir(folder);
        end
        filename = fullfile(folder, 'ear_thresholds.txt');
            
        % Step 4: Write bits to file (one per line)
        fid = fopen(filename, 'w');
        if fid == -1
        disp("Failed to open file for writing.");
        return;
        end

        fprintf(fid, '%c\n', allBits); % each character ('1' or '0') on a new line
        fclose(fid);

        disp(['Wrote audio chunk to: ' filename]);
% Step 3: Prepare output folder
        folder = fullfile(getenv('USERPROFILE'), 'Desktop', 'Telecom Umut', ...
                                  'EE3001_FinalProject_EG_IUD', 'MATLAB files');
        if ~exist(folder, 'dir')
                    mkdir(folder);
        end
        filename = fullfile(folder, 'ear_thresholds2.txt');
            
        % Step 4: Write bits to file (one per line)
        fid = fopen(filename, 'w');
        if fid == -1
        disp("Failed to open file for writing.");
        return;
        end

        fprintf(fid, '%c\n', allBits); % each character ('1' or '0') on a new line
        fclose(fid);

        disp(['Wrote audio chunk to: ' filename]);
        
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
    app.ThresholdsLeft = nan(1, length(app.Frequencies));
    app.ThresholdsRight = nan(1, length(app.Frequencies));
    app.CurrentFreqIdx = 1;
    app.CurrentdBIdx = 1;
    app.TestRunning = true;

    app.CurrentEar = "Left";  % Her zaman sol kulaktan başla
    playCurrentTone(app);
        end

        % Button pushed function: HeardButton
        function HeardButtonPushed(app, event)
            dB = app.dBLevels(app.CurrentdBIdx);
            idx = app.CurrentFreqIdx;

        if app.CurrentEar == "Left"
        app.ThresholdsLeft(idx) = dB;
        else
        app.ThresholdsRight(idx) = dB;
        end

    moveToNextFrequency(app);   
        end

        % Button pushed function: NotHearButton
        function NotHearButtonPushed(app, event)
    if app.CurrentdBIdx < length(app.dBLevels)
        app.CurrentdBIdx = app.CurrentdBIdx + 1;
        playCurrentTone(app);
    else
        idx = app.CurrentFreqIdx;
        if app.CurrentEar == "Left"
            app.ThresholdsLeft(idx) = NaN;
        else
            app.ThresholdsRight(idx) = NaN;
        end
        moveToNextFrequency(app);
    end
        end

        % Value changed function: StartAudioButton
        function StartAudioButtonValueChanged(app, event)
            value = app.StartAudioButton.Value;
            if value == 1
                disp("Starting real-time audio recording...");
                app.AudioTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', app.duration, ...
                    'TimerFcn', @(~,~)recordChunk(app));
                start(app.AudioTimer);
            else
                if ~isempty(app.AudioTimer) && isvalid(app.AudioTimer)
                    stop(app.AudioTimer);
                    delete(app.AudioTimer);
                    disp("Audio recording stopped.");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'StartTestButton';
            app.HeardButton = uibutton(app.UIFigure, 'push');
            app.HeardButton.ButtonPushedFcn = createCallbackFcn(app, @HeardButtonPushed, true);
            app.HeardButton.Text = 'HeardButton';
            app.NotHearButton = uibutton(app.UIFigure, 'push');
            app.NotHearButton.ButtonPushedFcn = createCallbackFcn(app, @NotHearButtonPushed, true);
            app.NotHearButton.Text = 'NotHearButton';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'StatusLamp';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StartAudioButton = uibutton(app.UIFigure, 'state');
            app.StartAudioButton.ValueChangedFcn = createCallbackFcn(app, @StartAudioButtonValueChanged, true);
            app.StartAudioButton.Text = 'StartAudio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = heaaare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end